classdef TT_PLUTO_Spectrum_Analysier_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TerayTech2021ADALMPLUTOGUILabel  matlab.ui.control.Label
        StatusLampLabel             matlab.ui.control.Label
        StatusLamp                  matlab.ui.control.Lamp
        InitializeButton            matlab.ui.control.Button
        ReleaseButton               matlab.ui.control.Button
        StartFreqSpinnerLabel       matlab.ui.control.Label
        StartFreqSpinner            matlab.ui.control.Spinner
        EndFreqSpinnerLabel         matlab.ui.control.Label
        EndFreqSpinner              matlab.ui.control.Spinner
        StepPointsSpinnerLabel      matlab.ui.control.Label
        StepPointsSpinner           matlab.ui.control.Spinner
        SweepStartButton            matlab.ui.control.Button
        FreqSetUpdateButton         matlab.ui.control.Button
        RXGainSpinnerLabel          matlab.ui.control.Label
        RXGainSpinner               matlab.ui.control.Spinner
        SwitchLabel                 matlab.ui.control.Label
        Switch                      matlab.ui.control.ToggleSwitch
        RXSignalAmplitudeEditFieldLabel  matlab.ui.control.Label
        RXSignalAmplitudeEditField  matlab.ui.control.NumericEditField
        UIAxes                      matlab.ui.control.UIAxes
        raw_i_data                  matlab.ui.control.UIAxes
        raw_q_data                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        tx,
        rx,
        amp,
        rl,
        freq,
        cal,
        ndec,
        sw,
        nstep,
        cfstart,
        cfstop,
        cfreq,
        rxgain
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            %adalm-pluto device initialization
            % Parameters for frequency scan:
            app.cfstart=70e6; % Start frequency
            app.cfstop=6000e6; % Stop frequency
            app.nstep=594; % Number of frequency steps
            app.rxgain = 50;
        end

        % Button pushed function: SweepStartButton
        function SweepStartButtonPushed(app, event)

            %% SCANS
            % Check that the PlutoSDR is active:
            if ~isempty(findPlutoRadio)
            fmin=0;
            df=app.rx.BasebandSampleRate/app.rx.SamplesPerFrame;
            fmax=app.rx.BasebandSampleRate/app.ndec-df;
            app.freq=linspace(fmin,fmax,app.rx.SamplesPerFrame/app.ndec)/1000; % freq in kHz
            index=app.sw.Frequency/df+1;
            
            %% CALIBRATION LOOP:
            %
            %input('Remove DUT - then press any key')
            for n = 1: app.nstep
            txfreq=app.cfreq(n);
            app.tx.CenterFrequency=txfreq; % Tuner frequency in Hz
            %tx.transmitRepeat(txdata); % Repeated transmission
            app.rx.CenterFrequency=app.tx.CenterFrequency; % Tuner frequency in Hz
            for m = 1:10 % Read multiple times to clear the Rx buffer
            data = app.rx(); % Fetch a frame from the Pluto SDR
            end
            ddata=decimate(double(data),app.ndec);
            spec=ifft(ddata); % Signal amplitude at 5kHz is in spec(51)
            app.cal(n)=abs(spec(index));
            plot(app.UIAxes,1e-6*app.cfreq,app.cal);
            plot(app.raw_i_data,real(ddata));plot(app.raw_q_data,imag(ddata));
            app.RXSignalAmplitudeEditField.Value = app.cal(n);
            drawnow;
            end
            end
        end

        % Button pushed function: ReleaseButton
        function ReleaseButtonPushed(app, event)
            release(app.rx);
            release(app.tx);
        end

        % Value changed function: StartFreqSpinner
        function StartFreqSpinnerValueChanged(app, event)
            app.cfstart = app.StartFreqSpinner.Value*1e6;
            
        end

        % Value changed function: EndFreqSpinner
        function EndFreqSpinnerValueChanged(app, event)
            app.cfstop = app.EndFreqSpinner.Value*1e6;
            
        end

        % Value changed function: StepPointsSpinner
        function StepPointsSpinnerValueChanged(app, event)
            app.nstep = app.StepPointsSpinner.Value;
            
        end

        % Button pushed function: FreqSetUpdateButton
        function FreqSetUpdateButtonPushed(app, event)
            
            app.cfreq=linspace(app.cfstart,app.cfstop,app.nstep);
            app.cal=zeros(app.nstep,1); % Array for calibration amplitudes
            app.amp=zeros(app.nstep,1); % Array for DUT amplitudes
            app.rl=zeros(app.nstep,1); % Array for DUT return loss
            app.tx = comm.SDRTxPluto; % SDRTxPluto system object for transmitter
            app.tx.CenterFrequency=app.cfreq(1); % Tuner frequency in Hz
            app.tx.Gain=-30; % Transmitter gain in dB (-89.75 to 0 dB)
            app.tx.BasebandSampleRate=1e5; % DAC sampling rate in Hz
            % Generate the 5kHz test tone:
            app.sw = dsp.SineWave;
            app.sw.Amplitude = 1;
            app.sw.Frequency = 5e3;
            app.sw.ComplexOutput = true;
            app.sw.SampleRate = app.tx.BasebandSampleRate;
            app.sw.SamplesPerFrame = 5000;
            txdata = conj(app.sw()); % Use conj() to generate the lower sideband
            
            app.rx = comm.SDRRxPluto; % SDRRxPluto system object for receiver
            app.rx.CenterFrequency=app.tx.CenterFrequency; % Tuner frequency in Hz
            app.rx.GainSource='Manual'; % Disables the AGC
            app.rx.Gain=app.rxgain; % Tuner gain in dB (-4 to 71 dB)
            app.rx.BasebandSampleRate=app.tx.BasebandSampleRate; % ADC sampling rate in Hz
            app.rx.SamplesPerFrame=1000; % Output data frame size
            app.rx.OutputDataType='int16'; % Output data type
            app.ndec=4; % Decimation factor
            
        end

        % Value changed function: RXGainSpinner
        function RXGainSpinnerValueChanged(app, event)
            app.rxgain = app.RXGainSpinner.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TerayTech2021ADALMPLUTOGUILabel = uilabel(app.UIFigure);
            app.TerayTech2021ADALMPLUTOGUILabel.Text = 'TerayTech 2021   ADALM-PLUTO GUI';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.ReleaseButton = uibutton(app.UIFigure, 'push');
            app.ReleaseButton.ButtonPushedFcn = createCallbackFcn(app, @ReleaseButtonPushed, true);
            app.ReleaseButton.Text = 'Release';
            app.StartFreqSpinnerLabel = uilabel(app.UIFigure);
            app.StartFreqSpinnerLabel.Text = 'Start Freq.';
            app.StartFreqSpinner = uispinner(app.UIFigure);
            app.StartFreqSpinner.Limits = [70 6000];
            app.StartFreqSpinner.ValueChangedFcn = createCallbackFcn(app, @StartFreqSpinnerValueChanged, true);
            app.StartFreqSpinner.Value = 70;
            app.EndFreqSpinnerLabel = uilabel(app.UIFigure);
            app.EndFreqSpinnerLabel.Text = 'End Freq.';
            app.EndFreqSpinner = uispinner(app.UIFigure);
            app.EndFreqSpinner.Limits = [70 6000];
            app.EndFreqSpinner.ValueChangedFcn = createCallbackFcn(app, @EndFreqSpinnerValueChanged, true);
            app.EndFreqSpinner.Value = 6000;
            app.StepPointsSpinnerLabel = uilabel(app.UIFigure);
            app.StepPointsSpinnerLabel.Text = 'Step Points';
            app.StepPointsSpinner = uispinner(app.UIFigure);
            app.StepPointsSpinner.ValueChangedFcn = createCallbackFcn(app, @StepPointsSpinnerValueChanged, true);
            app.StepPointsSpinner.Value = 594;
            app.SweepStartButton = uibutton(app.UIFigure, 'push');
            app.SweepStartButton.ButtonPushedFcn = createCallbackFcn(app, @SweepStartButtonPushed, true);
            app.SweepStartButton.Text = 'Sweep Start';
            app.FreqSetUpdateButton = uibutton(app.UIFigure, 'push');
            app.FreqSetUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @FreqSetUpdateButtonPushed, true);
            app.FreqSetUpdateButton.Text = 'Freq Set Update';
            app.RXGainSpinnerLabel = uilabel(app.UIFigure);
            app.RXGainSpinnerLabel.Text = 'RX Gain';
            app.RXGainSpinner = uispinner(app.UIFigure);
            app.RXGainSpinner.Limits = [-4 71];
            app.RXGainSpinner.ValueChangedFcn = createCallbackFcn(app, @RXGainSpinnerValueChanged, true);
            app.RXGainSpinner.Value = 50;
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.RXSignalAmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.RXSignalAmplitudeEditFieldLabel.Text = 'RX Signal Amplitude';
            app.RXSignalAmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.RXSignalAmplitudeEditField.ValueDisplayFormat = '%.3f';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Spectrum Analyzer')
            xlabel(app.UIAxes, 'Frequency / MHz')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.raw_i_data = uiaxes(app.UIFigure);
            title(app.raw_i_data, 'Raw I channal Data in realtime')
            xlabel(app.raw_i_data, 't')
            ylabel(app.raw_i_data, 'Amplitude')
            zlabel(app.raw_i_data, 'Z')
            app.raw_q_data = uiaxes(app.UIFigure);
            title(app.raw_q_data, 'Raw Q channal Data in realtime')
            xlabel(app.raw_q_data, 't')
            ylabel(app.raw_q_data, 'Amplitude')
            zlabel(app.raw_q_data, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TT_PLUTO_Spectrum_Analysier_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end